classdef ComparaANC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ComparativeANCperformancesUIFigure  matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        AttenuationTab         matlab.ui.container.Tab
        ModoAt                 matlab.ui.container.ButtonGroup
        Selected_At            matlab.ui.control.RadioButton
        all_At                 matlab.ui.control.RadioButton
        allfromsimu_At         matlab.ui.control.RadioButton
        allfrommic_At          matlab.ui.control.RadioButton
        Button                 matlab.ui.control.Button
        AtAxes                 matlab.ui.control.UIAxes
        TimeevolutionTab       matlab.ui.container.Tab
        ModoTiempo             matlab.ui.container.ButtonGroup
        Selected_t             matlab.ui.control.RadioButton
        Onlyallnoisesignals_t  matlab.ui.control.RadioButton
        Onlyallerrorsigals_t   matlab.ui.control.RadioButton
        Onlynoisesignalsfromselecedmic_t  matlab.ui.control.RadioButton
        Onlyerrorsignalsfromselectemic_t  matlab.ui.control.RadioButton
        Button_2               matlab.ui.control.Button
        timeAxes               matlab.ui.control.UIAxes
        SpectrumTab            matlab.ui.container.Tab
        Modof                  matlab.ui.container.ButtonGroup
        Selected_f             matlab.ui.control.RadioButton
        Onlyallnoisesignals_f  matlab.ui.control.RadioButton
        Onlyallerrorsigals_f   matlab.ui.control.RadioButton
        Onlynoisesignalsfromselecedmic_f  matlab.ui.control.RadioButton
        Onlyerrorsignalsfromselectemic_f  matlab.ui.control.RadioButton
        Button_3               matlab.ui.control.Button
        fAxes                  matlab.ui.control.UIAxes
        RunningtimeTab         matlab.ui.container.Tab
        Modort                 matlab.ui.container.ButtonGroup
        Selected_rt            matlab.ui.control.RadioButton
        all_rt                 matlab.ui.control.RadioButton
        Button_4               matlab.ui.control.Button
        rtAxes                 matlab.ui.control.UIAxes
        InfoTextAreaLabel      matlab.ui.control.Label
        Info                   matlab.ui.control.TextArea
        Open                   matlab.ui.control.Button
        Confi                  matlab.ui.control.Button
        MeasuresDropDownLabel  matlab.ui.control.Label
        MeasuresDropDown       matlab.ui.control.DropDown
        RunningTimeCheckBox    matlab.ui.control.CheckBox
        micDropDownLabel       matlab.ui.control.Label
        micDropDown            matlab.ui.control.DropDown
        NoiseCheckBox          matlab.ui.control.CheckBox
        ErrorSignalCheckBox    matlab.ui.control.CheckBox
        AttenuationCheckBox    matlab.ui.control.CheckBox
        elimina                matlab.ui.control.Button
        RunningtimeperNodemsEditFieldLabel  matlab.ui.control.Label
        MulltpernodeLabel      matlab.ui.control.Label
        MultEdit               matlab.ui.control.NumericEditField
        RuntimeEdit            matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        Parametros_conf % Description
    end
    
    properties (Access = private)
        fs % Description
        simu=[];
        numsimu=0;
        namesimu={};
        numic=[];
        numsamples=[];
        micselect=[];
        file_v={};
        signals;
        simuactual;
        checkrt=[];
        checknoise={};
        checkerror={};
        checkatt={};
        lineastd={};
        lineaste={};
        lineasfd={};
        lineasfe={};
        lineasAt={};
        lineastr={};

    end
    
    methods (Access = private)
        
        function results = addsimu(app)
            if app.numsimu>1
                  if app.fs==app.signals.fs
                        else
                        msgbox(['The sample rate does not match with other simualtions results. The result of the current simulation will be considered sampled at ' int2str(app.fs) 'kHz.' ]);
                  end
            
            else
                  app.fs=app.signals.fs;  
            end


            [samples,mic]=size(app.signals.d);
            exceso=length(app.signals.e(:,1))-length(app.signals.d(:,1));
            app.signals.e=app.signals.e(1+exceso:end,:);
            app.simu=[app.simu app.signals];
            app.numic=[app.numic mic];
            app.numsamples=[app.numsamples samples];
            app.micselect=[app.micselect 1];
            app.checkrt=[app.checkrt app.numsimu==1];
            checkini=zeros(mic,1);
            checkini(1)=(app.numsimu==1);
            app.checknoise=[app.checknoise checkini];
            app.checkerror=[app.checkerror checkini];
            app.checkatt=[app.checkatt checkini];
            calculalineast(app);
            calculalineasf(app);
            calculalineasAt(app);
            dibuja(app)
            % Seguir añadiendo
        end
        
        function results = delsimu(app)
         
            % Tenemos que invisibilizar las lineas antes de eliminarlas
            % para que no se queden permanentes
                for m=1:app.numic(app.simuactual)
                    set(app.lineastd{app.simuactual}(m),'Visible',0);
                    set(app.lineaste{app.simuactual}(m),'Visible',0);
                    set(app.lineasfd{app.simuactual}(m),'Visible',0);
                    set(app.lineasfe{app.simuactual}(m),'Visible',0);
                    set(app.lineasAt{app.simuactual}(m),'Visible',0);
                end
                set(app.lineastr{app.simuactual},'Visible',0);
            
            app.simu(app.simuactual)=[];
            app.numic(app.simuactual)=[];
            app.numsamples(app.simuactual)=[];
            app.micselect(app.simuactual)=[];
            app.file_v(app.simuactual)=[];
            app.namesimu(app.simuactual)=[];
            app.checkrt(app.simuactual)=[];
            app.checknoise(app.simuactual)=[];
            app.checkerror(app.simuactual)=[];
            app.checkatt(app.simuactual)=[];
            
            app.lineastd(app.simuactual)=[];
            app.lineaste(app.simuactual)=[];
            app.lineasfd(app.simuactual)=[];
            app.lineasfe(app.simuactual)=[];
            app.lineastr(app.simuactual)=[];
            app.lineasAt(app.simuactual)=[];

            
            for cad=app.simuactual:app.numsimu-1
            app.namesimu(cad)={[int2str(cad),' ',char(app.file_v(app.simuactual))]};
            end
            app.MeasuresDropDown.Items=app.namesimu;
        end
        
        function results = actualizafrontal(app)
           if app.numsimu>0
                app.MeasuresDropDown.Enable=1;
                app.MeasuresDropDown.Items=app.namesimu;
                app.MeasuresDropDown.Value=app.namesimu(app.simuactual);
                %simuactual=str2num(app.MeasuresDropDown.Value(1:2));
                
                app.Info.Value=app.simu(app.simuactual).str;
                app.RuntimeEdit.Value=app.simu(app.simuactual).tm*1000;
                app.MultEdit.Value=app.simu(app.simuactual).Mult;
                
                app.micDropDown.Enable=1;
                app.micDropDown.Items = (string(1:app.numic(app.simuactual)));
                app.micDropDown.Value=app.micDropDown.Items(app.micselect(app.simuactual));
                app.RunningTimeCheckBox.Enable=1;
                app.NoiseCheckBox.Enable=1;
                app.ErrorSignalCheckBox.Enable=1;
                app.AttenuationCheckBox.Enable=1;
                app.elimina.Enable=1;
                                
                app.RunningTimeCheckBox.Value=app.checkrt(app.simuactual);
                app.NoiseCheckBox.Value=app.checknoise{app.simuactual}(app.micselect(app.simuactual));
                app.ErrorSignalCheckBox.Value=app.checkerror{app.simuactual}(app.micselect(app.simuactual));
                app.AttenuationCheckBox.Value=app.checkatt{app.simuactual}(app.micselect(app.simuactual));
                
                
                else
                app.Info.Value='';
                app.RuntimeEdit.Value=0;
                app.MultEdit.Value=0;
                app.MeasuresDropDown.Enable=0;
                app.MeasuresDropDown.Items={};
                app.micDropDown.Enable=0;
                app.micDropDown.Items = {};
                app.RunningTimeCheckBox.Enable=0;
                app.NoiseCheckBox.Enable=0;
                app.ErrorSignalCheckBox.Enable=0;
                app.AttenuationCheckBox.Enable=0;
                app.elimina.Enable=0;
                
                app.RunningTimeCheckBox.Value=0;
                app.NoiseCheckBox.Value=0;
                app.ErrorSignalCheckBox.Value=0;
                app.AttenuationCheckBox.Value=0;
                
                end 
        end
        
        function results = calculalineast(app)
            t=(0:length(app.signals.d(:,1))-1)*(1/(1000*app.fs));
            lineas_estasimud=[];
            lineas_estasimue=[];
            for linea=1:app.numic(app.simuactual)
            lineas_estasimud=[lineas_estasimud line(app.timeAxes,t,app.signals.d(:,linea))];
            lineas_estasimue=[lineas_estasimue line(app.timeAxes,t,app.signals.e(:,linea))];
            end
            app.lineastd=[app.lineastd {lineas_estasimud}];
            app.lineaste=[app.lineaste {lineas_estasimue}];
                       
            t2=1:length(app.signals.t);
            
            app.lineastr=[app.lineastr {line(app.rtAxes,t2,app.signals.t)}];
        end
        
      function results = calculalineasAt(app)
                t=(0:length(app.signals.d(:,1))-1)*(1/(1000*app.fs));
            lineas_estasimu=[];
            
            for linea=1:app.numic(app.simuactual)
                Pd=filter(1-app.Parametros_conf.Beta,[1 -app.Parametros_conf.Beta],app.signals.d(:,linea).^2,app.signals.d(1,linea).^2);
                Pe=filter(1-app.Parametros_conf.Beta,[1 -app.Parametros_conf.Beta],app.signals.e(:,linea).^2,app.signals.e(1,linea).^2);

                lineas_estasimu=[lineas_estasimu line(app.AtAxes,t,10*log10((max(1e-10,Pe))./(max(1e-10,Pd))))];
            
            end
            app.lineasAt=[app.lineasAt {lineas_estasimu}];
            
        end
        
        function results = calculalineasf(app)
            %pwelch(a,1000,100,256,2000)
            tam=round(length(app.signals.d(:,1))/app.Parametros_conf.TamTotal);
            D=pwelch(app.signals.d(end-tam+1:end,:),round(tam/app.Parametros_conf.Promedios),round(tam/app.Parametros_conf.Solape),app.Parametros_conf.Nfft,app.fs);
            E=pwelch(app.signals.e(end-tam+1:end,:),round(tam/app.Parametros_conf.Promedios),round(tam/app.Parametros_conf.Solape),app.Parametros_conf.Nfft,app.fs);
            f=(0:length(D(:,1))-1)*1000*app.fs/length(D(:,1));
            lineas_estasimud=[];
            lineas_estasimue=[];
            for linea=1:app.numic(app.simuactual)
            lineas_estasimud=[lineas_estasimud line(app.fAxes,f,10*log10(D(:,linea)))];
            lineas_estasimue=[lineas_estasimue line(app.fAxes,f,10*log10(E(:,linea)))];
            end
            app.lineasfd=[app.lineasfd {lineas_estasimud}];
            app.lineasfe=[app.lineasfe {lineas_estasimue}];

           
        end
        
        
        function results = dibuja(app)
           
           map=prism(256); % Mapa de colores
           
           % Dibuja atenuación
           modo=sum(app.ModoAt.SelectedObject.Text);
           switch modo
               case 809    % dibuja seleccionadas
                 for s=1:app.numsimu
                    for m=1:app.numic(s)
                    set(app.lineasAt{s}(m),'Visible',app.checkatt{s}(m),'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                    end
                 end
        
               case 2205   % dibuja todas
                 for s=1:app.numsimu
                    for m=1:app.numic(s)
                    set(app.lineasAt{s}(m),'Visible',1,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                    end
                 end

                   
               case 4814    % Dibuja las de la simulaciación actual
                 for s=1:app.numsimu
                    for m=1:app.numic(s)
                        if s==app.simuactual
                    set(app.lineasAt{s}(m),'Visible',1,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                        else
                            set(app.lineasAt{s}(m),'Visible',0,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                        end
                        end
                 end

                   
               case 4373    % Dibuja las del micrófono actual
                 for s=1:app.numsimu
                    for m=1:app.numic(s)
                        if m==app.micselect(app.simuactual)
                    set(app.lineasAt{s}(m),'Visible',1,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                        else
                        set(app.lineasAt{s}(m),'Visible',0,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                        end
                        end
                 end

                   
           end


           % Dibuja espectros
           modo=sum(app.Modof.SelectedObject.Text);
           switch modo
               case 809   %dibuja seleccionadas
                 for s=1:app.numsimu
                    for m=1:app.numic(s)
                    set(app.lineasfd{s}(m),'Visible',app.checknoise{s}(m),'Color',map(max(1,rem(s+(m-1)*8,256)),:)*0.2,'LineStyle','--');
                    set(app.lineasfe{s}(m),'Visible',app.checkerror{s}(m),'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                 end
                 end

               case 2122   %dibuja señales de ruido
                 for s=1:app.numsimu
                    for m=1:app.numic(s)
                    set(app.lineasfd{s}(m),'Visible',1,'Color',map(max(1,rem(s+(m-1)*8,256)),:)*0.2,'LineStyle','--');
                    set(app.lineasfe{s}(m),'Visible',0,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                 end
                 end

               case 2024   %dibuja señales de error
                 for s=1:app.numsimu
                    for m=1:app.numic(s)
                    set(app.lineasfd{s}(m),'Visible',0,'Color',map(max(1,rem(s+(m-1)*8,256)),:)*0.2,'LineStyle','--');
                    set(app.lineasfe{s}(m),'Visible',1,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                 end
                 end

               case 3347   %dibuja señales de ruido de mic seleccionado
                 for s=1:app.numsimu
                        for m=1:app.numic(s)
                        if m==app.micselect(app.simuactual)
                            set(app.lineasfd{s}(m),'Visible',1,'Color',map(max(1,rem(s+(m-1)*8,256)),:)*0.2,'LineStyle','--');
                        else
                    set(app.lineasfd{s}(m),'Visible',0,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                        end
                    
                    set(app.lineasfe{s}(m),'Visible',0,'Color',map(max(1,rem(1+s+(m-1)*8,256)),:));
                 end
                 end
               case 3475   %dibuja señales de error de mic seleccionado
                 for s=1:app.numsimu
                        for m=1:app.numic(s)
                        if m==app.micselect(app.simuactual)
                            set(app.lineasfe{s}(m),'Visible',1,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                        else
                    set(app.lineasfe{s}(m),'Visible',0,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                        end
                    
                    set(app.lineasfd{s}(m),'Visible',0,'Color',map(max(1,rem(1+s+(m-1)*8,256)),:));
                 end
                 end

                 
           end

           
           % Dibuja Tiempo ejecución
           modo=sum(app.Modort.SelectedObject.Text);
           switch modo
               case 809   % representa seleccionadas
                    for s=1:app.numsimu
                        set(app.lineastr{s},'Visible',app.checkrt(s),'Color',map(max(1,rem(s,256)),:));
                    end
               
               case 1214  % Representa todas
                     for s=1:app.numsimu
                        set(app.lineastr{s},'Visible',1,'Color',map(max(1,rem(s,256)),:));
                    end
           end
           
           % Dibuja tiempo
           modo=sum(app.ModoTiempo.SelectedObject.Text);
           switch modo
               case 809   %dibuja seleccionadas
                 for s=1:app.numsimu
                    for m=1:app.numic(s)
                    set(app.lineastd{s}(m),'Visible',app.checknoise{s}(m),'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                    set(app.lineaste{s}(m),'Visible',app.checkerror{s}(m),'Color',map(max(1,rem(1+s+(m-1)*8,256)),:));
                 end
                 end

               case 2122   %dibuja señales de ruido
                 for s=1:app.numsimu
                    for m=1:app.numic(s)
                    set(app.lineastd{s}(m),'Visible',1,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                    set(app.lineaste{s}(m),'Visible',0,'Color',map(max(1,rem(1+s+(m-1)*8,256)),:));
                 end
                 end

               case 2024   %dibuja señales de error
                 for s=1:app.numsimu
                    for m=1:app.numic(s)
                    set(app.lineastd{s}(m),'Visible',0,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                    set(app.lineaste{s}(m),'Visible',1,'Color',map(max(1,rem(1+s+(m-1)*8,256)),:));
                 end
                 end

               case 3347   %dibuja señales de ruido de mic seleccionado
                 for s=1:app.numsimu
                        for m=1:app.numic(s)
                        if m==app.micselect(app.simuactual)
                            set(app.lineastd{s}(m),'Visible',1,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                        else
                    set(app.lineastd{s}(m),'Visible',0,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                        end
                    
                    set(app.lineaste{s}(m),'Visible',0,'Color',map(max(1,rem(1+s+(m-1)*8,256)),:));
                 end
                 end
               case 3475   %dibuja señales de error de mic seleccionado
                 for s=1:app.numsimu
                        for m=1:app.numic(s)
                        if m==app.micselect(app.simuactual)
                            set(app.lineaste{s}(m),'Visible',1,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                        else
                    set(app.lineaste{s}(m),'Visible',0,'Color',map(max(1,rem(s+(m-1)*8,256)),:));
                        end
                    
                    set(app.lineastd{s}(m),'Visible',0,'Color',map(max(1,rem(1+s+(m-1)*8,256)),:));
                 end
                 end

                 
           end
            
            drawnow
            
        end


        function results = desacopla_eje(app,eje)
            fig=figure;
            neweje=gca(fig);
            %app.ejepublico=eje;
            copyobj(eje.Children,neweje);
        end
    end
    
    methods (Access = public)


        function results = recalculalineasf(app)
            lineas=get(app.fAxes,'Children');
            for l=1:length(lineas)
                lineas(l).Visible=0;
            end
            app.lineasfd=[];
            app.lineasfe=[];
            vuelvesimu=app.simuactual;
            for sim=1:app.numsimu
                app.signals=app.simu(sim);
                app.simuactual=sim;
                calculalineasf(app)
            end
            app.simuactual=vuelvesimu;
            dibuja(app);
        end
        
        function results = recalculalineasAt(app)
            lineas=get(app.AtAxes,'Children');
            for l=1:length(lineas)
                lineas(l).Visible=0;
            end

            app.lineasAt=[];
            vuelvesimu=app.simuactual;
            for sim=1:app.numsimu
                app.signals=app.simu(sim);
                app.simuactual=sim;
                calculalineasAt(app)
            end
            app.simuactual=vuelvesimu;
            dibuja(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, in)
            
            app.MeasuresDropDown.Items={};
            app.Parametros_conf.Beta=0.99;
            app.Parametros_conf.TamTotal=10;
            app.Parametros_conf.Nfft=2048;
            app.Parametros_conf.Promedios=1;
            app.Parametros_conf.Solape=50;
            app.Parametros_conf.Cambia=0;
            
            if nargin>1
                app.numsimu=app.numsimu+1;
                app.simuactual=app.numsimu;
                app.signals=in;
                app.fs=in.fs;
                app.namesimu={'1 New Sim.'};
                app.file_v={'New Sim.'};

                addsimu(app);        
                actualizafrontal(app);


            else
                
            end
        end

        % Button pushed function: Open
        function OpenButtonPushed(app, event)
            [file,path]=uigetfile('*.anc');
            if path==0
            else
                app.numsimu=app.numsimu+1;
                load([path file],'-mat');
                app.file_v=[app.file_v file(1:end-4)];
                app.signals=signals;
                cadena=[int2str(app.numsimu),' ',file(1:end-4)];
                app.namesimu=[app.namesimu cadena];
                app.simuactual=app.numsimu;
                addsimu(app);
                actualizafrontal(app);
              
            end
        end

        % Button pushed function: elimina
        function eliminaButtonPushed(app, event)
            app.simuactual=str2num(app.MeasuresDropDown.Value(1:2));
            delsimu(app)
            app.numsimu=app.numsimu-1;
            app.simuactual=min(app.numsimu,app.simuactual);
            actualizafrontal(app);
            dibuja(app);
            
            
        end

        % Value changed function: MeasuresDropDown
        function MeasuresDropDownValueChanged(app, event)
            value = app.MeasuresDropDown.Value;
            app.simuactual=str2num(value(1:2));
            actualizafrontal(app);
            dibuja(app)
            
        end

        % Value changed function: micDropDown
        function micDropDownValueChanged(app, event)
            value = app.micDropDown.Value;
            app.micselect(app.simuactual)=str2num(value);
            actualizafrontal(app);
            dibuja(app);
        end

        % Value changed function: RunningTimeCheckBox
        function RunningTimeCheckBoxValueChanged(app, event)
            value = app.RunningTimeCheckBox.Value;
            app.checkrt(app.simuactual)=value; 
            dibuja(app);
                
            
        end

        % Value changed function: NoiseCheckBox
        function NoiseCheckBoxValueChanged(app, event)
            value = app.NoiseCheckBox.Value;
            app.checknoise{app.simuactual}(app.micselect(app.simuactual))=value;
            dibuja(app)
        end

        % Value changed function: ErrorSignalCheckBox
        function ErrorSignalCheckBoxValueChanged(app, event)
            value = app.ErrorSignalCheckBox.Value;
            app.checkerror{app.simuactual}(app.micselect(app.simuactual))=value;
            dibuja(app)
            
        end

        % Value changed function: AttenuationCheckBox
        function AttenuationCheckBoxValueChanged(app, event)
            value = app.AttenuationCheckBox.Value;
            app.checkatt{app.simuactual}(app.micselect(app.simuactual))=value;
            dibuja(app)
        end

        % Selection changed function: ModoTiempo
        function ModoTiempoSelectionChanged(app, event)
            selectedButton = app.ModoTiempo.SelectedObject;
            %sum(selectedButton.Text)
            dibuja(app);
        end

        % Selection changed function: Modort
        function ModortSelectionChanged(app, event)
            selectedButton = app.Modort.SelectedObject;
            dibuja(app);
        end

        % Selection changed function: Modof
        function ModofSelectionChanged(app, event)
            selectedButton = app.Modof.SelectedObject;
            dibuja(app);
        end

        % Selection changed function: ModoAt
        function ModoAtSelectionChanged(app, event)
            selectedButton = app.ModoAt.SelectedObject;
            dibuja(app);
        end

        % Button pushed function: Confi
        function ConfiButtonPushed(app, event)
            confcomp(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            desacopla_eje(app,app.AtAxes);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            desacopla_eje(app,app.timeAxes);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            desacopla_eje(app,app.fAxes);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            desacopla_eje(app,app.rtAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ComparativeANCperformancesUIFigure = uifigure('Visible', 'off');
            app.ComparativeANCperformancesUIFigure.Name = 'Comparative ANC performances';
            app.TabGroup = uitabgroup(app.ComparativeANCperformancesUIFigure);
            app.AttenuationTab = uitab(app.TabGroup);
            app.AttenuationTab.Title = 'Attenuation';
            app.ModoAt = uibuttongroup(app.AttenuationTab);
            app.ModoAt.SelectionChangedFcn = createCallbackFcn(app, @ModoAtSelectionChanged, true);
            app.ModoAt.Title = 'Visible attenuation curves';
            app.Selected_At = uiradiobutton(app.ModoAt);
            app.Selected_At.Text = 'Selected';
            app.Selected_At.Value = true;
            app.all_At = uiradiobutton(app.ModoAt);
            app.all_At.Text = 'All attenuation curves';
            app.allfromsimu_At = uiradiobutton(app.ModoAt);
            app.allfromsimu_At.Text = 'Only all attenuation curves from selected measure';
            app.allfrommic_At = uiradiobutton(app.ModoAt);
            app.allfrommic_At.Text = 'Only all attenuation curves from selected mic';
            app.Button = uibutton(app.AttenuationTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'redoarrow_rehace_1547.png';
            app.Button.Text = '';
            app.AtAxes = uiaxes(app.AttenuationTab);
            title(app.AtAxes, 'Attenuation')
            xlabel(app.AtAxes, 'seconds')
            ylabel(app.AtAxes, 'dB')
            app.TimeevolutionTab = uitab(app.TabGroup);
            app.TimeevolutionTab.Title = 'Time evolution';
            app.ModoTiempo = uibuttongroup(app.TimeevolutionTab);
            app.ModoTiempo.SelectionChangedFcn = createCallbackFcn(app, @ModoTiempoSelectionChanged, true);
            app.ModoTiempo.Title = 'Visible time evolution signals';
            app.Selected_t = uiradiobutton(app.ModoTiempo);
            app.Selected_t.Text = 'Selected';
            app.Selected_t.Value = true;
            app.Onlyallnoisesignals_t = uiradiobutton(app.ModoTiempo);
            app.Onlyallnoisesignals_t.Text = 'Only all noise signals';
            app.Onlyallerrorsigals_t = uiradiobutton(app.ModoTiempo);
            app.Onlyallerrorsigals_t.Text = 'Only all error sigals';
            app.Onlynoisesignalsfromselecedmic_t = uiradiobutton(app.ModoTiempo);
            app.Onlynoisesignalsfromselecedmic_t.Text = 'Only noise signals from seleced mic';
            app.Onlyerrorsignalsfromselectemic_t = uiradiobutton(app.ModoTiempo);
            app.Onlyerrorsignalsfromselectemic_t.Text = 'Only error signals from selected mic';
            app.Button_2 = uibutton(app.TimeevolutionTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'redoarrow_rehace_1547.png';
            app.Button_2.Text = '';
            app.timeAxes = uiaxes(app.TimeevolutionTab);
            title(app.timeAxes, 'Time evolution')
            xlabel(app.timeAxes, 'seconds')
            app.SpectrumTab = uitab(app.TabGroup);
            app.SpectrumTab.Title = 'Spectrum';
            app.Modof = uibuttongroup(app.SpectrumTab);
            app.Modof.SelectionChangedFcn = createCallbackFcn(app, @ModofSelectionChanged, true);
            app.Modof.Title = 'Visible time spectrum signals';
            app.Selected_f = uiradiobutton(app.Modof);
            app.Selected_f.Text = 'Selected';
            app.Selected_f.Value = true;
            app.Onlyallnoisesignals_f = uiradiobutton(app.Modof);
            app.Onlyallnoisesignals_f.Text = 'Only all noise signals';
            app.Onlyallerrorsigals_f = uiradiobutton(app.Modof);
            app.Onlyallerrorsigals_f.Text = 'Only all error sigals';
            app.Onlynoisesignalsfromselecedmic_f = uiradiobutton(app.Modof);
            app.Onlynoisesignalsfromselecedmic_f.Text = 'Only noise signals from seleced mic';
            app.Onlyerrorsignalsfromselectemic_f = uiradiobutton(app.Modof);
            app.Onlyerrorsignalsfromselectemic_f.Text = 'Only error signals from selected mic';
            app.Button_3 = uibutton(app.SpectrumTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'redoarrow_rehace_1547.png';
            app.Button_3.Text = '';
            app.fAxes = uiaxes(app.SpectrumTab);
            title(app.fAxes, 'Spectrum')
            xlabel(app.fAxes, 'Hz')
            ylabel(app.fAxes, 'dB (arbitrary reference)')
            app.RunningtimeTab = uitab(app.TabGroup);
            app.RunningtimeTab.Title = 'Running time';
            app.Modort = uibuttongroup(app.RunningtimeTab);
            app.Modort.SelectionChangedFcn = createCallbackFcn(app, @ModortSelectionChanged, true);
            app.Modort.Title = 'Visible run time evolution values';
            app.Selected_rt = uiradiobutton(app.Modort);
            app.Selected_rt.Text = 'Selected';
            app.Selected_rt.Value = true;
            app.all_rt = uiradiobutton(app.Modort);
            app.all_rt.Text = ' All measures';
            app.Button_4 = uibutton(app.RunningtimeTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'redoarrow_rehace_1547.png';
            app.Button_4.Text = '';
            app.rtAxes = uiaxes(app.RunningtimeTab);
            title(app.rtAxes, 'Execution time per node and iteration ')
            xlabel(app.rtAxes, 'Iteration')
            app.InfoTextAreaLabel = uilabel(app.ComparativeANCperformancesUIFigure);
            app.InfoTextAreaLabel.Text = 'Info:';
            app.Info = uitextarea(app.ComparativeANCperformancesUIFigure);
            app.Info.Editable = 'off';
            app.Open = uibutton(app.ComparativeANCperformancesUIFigure, 'push');
            app.Open.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.Open.Icon = '62917openfilefolder_109270.png';
            app.Open.Text = '';
            app.Confi = uibutton(app.ComparativeANCperformancesUIFigure, 'push');
            app.Confi.ButtonPushedFcn = createCallbackFcn(app, @ConfiButtonPushed, true);
            app.Confi.Icon = 'settingscog_87317.png';
            app.Confi.Text = '';
            app.MeasuresDropDownLabel = uilabel(app.ComparativeANCperformancesUIFigure);
            app.MeasuresDropDownLabel.Text = 'Measures:';
            app.MeasuresDropDown = uidropdown(app.ComparativeANCperformancesUIFigure);
            app.MeasuresDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasuresDropDownValueChanged, true);
            app.MeasuresDropDown.Enable = 'off';
            app.RunningTimeCheckBox = uicheckbox(app.ComparativeANCperformancesUIFigure);
            app.RunningTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunningTimeCheckBoxValueChanged, true);
            app.RunningTimeCheckBox.Enable = 'off';
            app.RunningTimeCheckBox.Text = 'Running-Time';
            app.micDropDownLabel = uilabel(app.ComparativeANCperformancesUIFigure);
            app.micDropDownLabel.Text = 'mic';
            app.micDropDown = uidropdown(app.ComparativeANCperformancesUIFigure);
            app.micDropDown.ValueChangedFcn = createCallbackFcn(app, @micDropDownValueChanged, true);
            app.micDropDown.Enable = 'off';
            app.NoiseCheckBox = uicheckbox(app.ComparativeANCperformancesUIFigure);
            app.NoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoiseCheckBoxValueChanged, true);
            app.NoiseCheckBox.Enable = 'off';
            app.NoiseCheckBox.Text = 'Noise';
            app.ErrorSignalCheckBox = uicheckbox(app.ComparativeANCperformancesUIFigure);
            app.ErrorSignalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ErrorSignalCheckBoxValueChanged, true);
            app.ErrorSignalCheckBox.Enable = 'off';
            app.ErrorSignalCheckBox.Text = 'Error Signal';
            app.AttenuationCheckBox = uicheckbox(app.ComparativeANCperformancesUIFigure);
            app.AttenuationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AttenuationCheckBoxValueChanged, true);
            app.AttenuationCheckBox.Enable = 'off';
            app.AttenuationCheckBox.Text = 'Attenuation';
            app.elimina = uibutton(app.ComparativeANCperformancesUIFigure, 'push');
            app.elimina.ButtonPushedFcn = createCallbackFcn(app, @eliminaButtonPushed, true);
            app.elimina.Icon = 'eliminar-simbolo.png';
            app.elimina.Enable = 'off';
            app.elimina.Text = '';
            app.RunningtimeperNodemsEditFieldLabel = uilabel(app.ComparativeANCperformancesUIFigure);
            app.RunningtimeperNodemsEditFieldLabel.Text = 'Running time per Node (ms)';
            app.MulltpernodeLabel = uilabel(app.ComparativeANCperformancesUIFigure);
            app.MulltpernodeLabel.Text = 'Mullt. per node';
            app.MultEdit = uieditfield(app.ComparativeANCperformancesUIFigure, 'numeric');
            app.MultEdit.Editable = 'off';
            app.RuntimeEdit = uieditfield(app.ComparativeANCperformancesUIFigure, 'numeric');
            app.RuntimeEdit.Editable = 'off';
            % Show the figure after all components are created
            app.ComparativeANCperformancesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComparaANC(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ComparativeANCperformancesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ComparativeANCperformancesUIFigure)
        end
    end
end