classdef analizaANC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ANCResultsVisualizerUIFigure  matlab.ui.Figure
        Slider                        matlab.ui.control.Slider
        ANConoffButton                matlab.ui.control.StateButton
        MicrophoneDropDownLabel       matlab.ui.control.Label
        MicrophoneDropDown            matlab.ui.control.DropDown
        PowerButtonGroup              matlab.ui.container.ButtonGroup
        LinealButton                  matlab.ui.control.RadioButton
        dBButton                      matlab.ui.control.RadioButton
        FrequencyButtonGroup          matlab.ui.container.ButtonGroup
        LinealButton_2                matlab.ui.control.RadioButton
        logButton                     matlab.ui.control.RadioButton
        Configura_Button              matlab.ui.control.Button
        Button                        matlab.ui.control.StateButton
        Guarda                        matlab.ui.control.Button
        compara                       matlab.ui.control.Button
        eje_TF                        matlab.ui.control.UIAxes
        eje_T                         matlab.ui.control.UIAxes
        eje_F                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         
         
         e=[];
         d=[];
         y=[];
         N=1;
         J=1;
         K=1;
        %nfft_f=1024;
         E=[];
         D=[];
         micselect=1;
         sonido=[];
         eje_t=[];
         eje_w=[];
         VisualizaPdB=0;
         Visualizaflog=0;
         linea_e;
         linea_d;
         linea_E;
         linea_D;
         buffer=1;
         audio_salida;
         NBuffer;
         espectro_actual=1;
         signals;


     % Description
    end
    
    properties (Access = public)
         fs=1;  % Khz
         muestrasventana=[];
         solape=50;
         nfft_tf=2048;
         bufferAudio=50;
         RefrescoGrafica=5;
         Etiqueta;
        
    end
    
    methods (Access = private)


        function results = reproduce(app)


            salida=app.e(:,app.micselect);
            NBuffer=ceil(length(salida)/app.bufferAudio);
            app.NBuffer=NBuffer;
            salida(NBuffer*(app.bufferAudio)+1)=0;
            salida=salida(1:end-1);
            Num_espectros=length(app.eje_t)-2;
            
            while app.Button.Value % buffer=Inicio:NBuffer
             
                if (app.buffer)>=NBuffer
                    app.buffer=1;
                    release(app.audio_salida);
                    app.Button.Value=0;
                    app.Button.Icon = 'play.png';
                    app.Slider.Value=0;
                    app.Configura_Button.Enable='on';
                    app.Guarda.Enable='on';
                    app.compara.Enable='on';
                  
              
                else
                    if rem(app.buffer,app.RefrescoGrafica)==0
                               
                   
            app.espectro_actual=1+floor(Num_espectros*app.Slider.Value/app.Slider.Limits(2));
            dibuja_f(app,app.espectro_actual);
                    end
                     if app.ANConoffButton.Value
                      salida=app.e(:,app.micselect);
                     else
                    salida=app.d(:,app.micselect);   
                    end
           
                   app.audio_salida(salida(1+(app.buffer-1)*app.bufferAudio:app.buffer*app.bufferAudio));
                    app.buffer=app.buffer+1;  
                    app.Slider.Value=(app.buffer*app.bufferAudio/(1000*app.fs));


                end
                drawnow
            end
            
        end
        
       
    end
    
    methods (Access = public)
        
         function results = resetea_audio(app)
            
            app.Slider.Limits=[0,(app.N-1+app.bufferAudio)/(1000*app.fs)];
            app.NBuffer=ceil(length(app.d(:,1))/app.bufferAudio);
            app.buffer=1;
            %release(app.audio_salida);
        end
        
        
        function calcula_espectro(app)
            ventana=hamming(app.muestrasventana);
            
            app.E=[];
            app.D=[];
            for nmic=1:app.K
            
            [s,w,t] = spectrogram(app.e(:,nmic),ventana,round(app.muestrasventana*app.solape/100),app.nfft_tf,1000);
            E(:,:,nmic)=abs(s);
            [s,w,t] = spectrogram(app.d(:,nmic),ventana,round(app.muestrasventana*app.solape/100),app.nfft_tf,1000);
            D(:,:,nmic)=abs(s);
                        
            end
            maximo=max(max(max(max(E))),max(max(max(D))));
            minimo=min(min(min(min(E))),min(min(min(D))));
            
            
            for nmic=1:app.K
            
            app.E(:,:,nmic)=[minimo*ones(length(w),1) E(:,:,nmic) maximo*ones(length(w),1)];
            app.D(:,:,nmic)=[minimo*ones(length(w),1) D(:,:,nmic) maximo*ones(length(w),1)];
                        
            end
            
            app.eje_t=[t(1);t(:);t(end)];
            app.eje_w=w;


            resetea_audio(app)
            
                  
        end


        function dibuja_tf(app)
            
            if app.ANConoffButton.Value
                
            espectro=app.E;
            else
                espectro=app.D;
            end
            if app.VisualizaPdB
                espectro=20*log10(espectro);
            end
            if app.Visualizaflog
                f=app.eje_w*app.fs;
                f(1)=f(2)/2;
                f=log2(f);
            else
                f=app.eje_w*app.fs;
            end
            
            pcolor(app.eje_TF,app.eje_t(2:end-1)/app.fs,f,espectro(:,2:end-1,app.micselect)); 
            if app.Visualizaflog
               % eje=[log2([50 100 200 400 800])]
                eje=app.eje_TF.YTick;
                app.eje_TF.YTickLabel=string(2.^eje);
            else
                app.eje_TF.YTickLabelMode='auto';
            end
            shading(app.eje_TF,"interp");    
            colorbar(app.eje_TF)


        end
        
        function dibuja_f(app,espectro_actual)
            
            if nargin==1
                espectro_actual=1;
            end
            
            if app.Visualizaflog
                f=app.eje_w*app.fs;
                f(1)=f(2)/2;
                f=log2(f);
                app.linea_D.XData=f;
                app.linea_E.XData=f;
                eje=app.eje_F.XTick;
                app.eje_F.XTickLabel=string(2.^eje);
            else
                f=app.eje_w*app.fs;
                app.linea_D.XData=f;
                app.linea_E.XData=f;
                app.eje_F.XTickLabelMode='auto';
            end


        if app.VisualizaPdB
        app.eje_F.YLim=20*log10([min(min(min(app.D))),max(max(max(app.D)))]);
        app.linea_D.YData=20*log10(app.D(:,espectro_actual,app.micselect));
        app.linea_E.YData=20*log10(app.E(:,espectro_actual,app.micselect));
        
        else
        app.eje_F.YLim=[min(min(min(app.D))),max(max(max(app.D)))];
        app.linea_D.YData=app.D(:,espectro_actual,app.micselect);
        app.linea_E.YData=app.E(:,espectro_actual,app.micselect);
        
        end
       
        if app.ANConoffButton.Value
            app.linea_D.Visible='off';
        else
            app.linea_D.Visible='on';
        end
        drawnow
            
        end
        
        function dibuja_t(app)
            t=[0:app.N-1]';
        app.eje_T.YLim=[min(min(min(app.d))),max(max(max(app.d)))];
        app.linea_d.XData=t/(1000*app.fs);
        app.linea_e.XData=t/(1000*app.fs);
        app.linea_d.YData=app.d(:,app.micselect);
        if app.ANConoffButton.Value
            app.linea_d.Visible='off';
        else
            app.linea_d.Visible='on';
        end
        
        app.linea_e.YData=app.e(:,app.micselect);
        app.linea_e.Visible='on';
            
            drawnow
            
        end
        
        function results = actualizaEtiqueta(app)
           app.signals.str=app.Etiqueta; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, in)
            
            if nargin>1
                Numfile=1;
            
            app.signals=in;
            app.d=in.d;
            app.y=in.y;
            app.fs=in.fs;
            app.Etiqueta=in.str;
            app.bufferAudio=ceil(50*app.fs);
            [~,app.J]=size(app.y);
            [app.N,app.K]=size(app.d);
            app.e=in.e(1:app.N,:);
            
            app.MicrophoneDropDown.Items = (string(1:app.K));
            
            app.muestrasventana=1000;
            
            app.linea_d=line('Parent',app.eje_T,'Color','r','Visible','on','XData',[0:app.N-1]','YData',zeros(app.N,1));
            app.linea_e=line('Parent',app.eje_T,'Color','g','Visible','on','XData',[0:app.N-1]','YData',zeros(app.N,1));
            
            app.linea_E=line('Parent',app.eje_F,'Color','g','Visible','on');
            app.linea_D=line('Parent',app.eje_F,'Color','r','Visible','off');
            
            
            calcula_espectro(app);
            dibuja_tf(app);
            dibuja_f(app);
            dibuja_t(app);
            else
                msgbox('An input struct argument is required. Example: AnalizaANC(signals)' )
            end


        end

        % Value changed function: ANConoffButton
        function ANConoffButtonValueChanged(app, event)
            value = app.ANConoffButton.Value;
            dibuja_tf(app);
            dibuja_t(app);
            dibuja_f(app,app.espectro_actual);
        end

        % Value changed function: MicrophoneDropDown
        function MicrophoneDropDownValueChanged(app, event)
            value = app.MicrophoneDropDown.Value;
            app.micselect=str2num(value);
             dibuja_tf(app);
             dibuja_t(app);
             dibuja_f(app,app.espectro_actual);
        end

        % Selection changed function: FrequencyButtonGroup
        function FrequencyButtonGroupSelectionChanged(app, event)
            selectedButton = app.FrequencyButtonGroup.SelectedObject;
            if selectedButton.Text(1)=='L'
                app.Visualizaflog=0;
            else
                app.Visualizaflog=1;
            end
            dibuja_tf(app)
            dibuja_f(app,app.espectro_actual);
        end

        % Selection changed function: PowerButtonGroup
        function PowerButtonGroupSelectionChanged(app, event)
            selectedButton = app.PowerButtonGroup.SelectedObject;
           if selectedButton.Text(1)=='L'
                app.VisualizaPdB=0;
            else
                app.VisualizaPdB=1;
            end
            
            dibuja_tf(app)
            dibuja_f(app,app.espectro_actual);
            
        end

        % Button pushed function: Configura_Button
        function Configura_ButtonPushed(app, event)
            conf(app);
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            if value
            app.Button.Icon = 'pause.png';
            app.Configura_Button.Enable='off';
            app.Guarda.Enable='off';
            app.compara.Enable='off';
            app.audio_salida=audioDeviceWriter('SampleRate',1000*app.fs);
            else
            app.Button.Icon = 'play.png';  
            release(app.audio_salida);
            app.Configura_Button.Enable='on';
            app.Guarda.Enable='on';
            app.compara.Enable='on';
            end
            drawnow
            reproduce(app);


        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            % Calcular buffer
            app.buffer=min(1+floor(value*1000*app.fs/app.bufferAudio),app.NBuffer);
            % Calcular espectro a representar
            Num_espectros=length(app.eje_t)-2;
            espectro_actual=1+floor(Num_espectros*value/app.Slider.Limits(2));
            app.espectro_actual=espectro_actual;
                  
            % dibujar espectro
            dibuja_f(app,espectro_actual);
        end

        % Button pushed function: Guarda
        function GuardaButtonPushed(app, event)
            %app.signals.str=app.Etiqueta;
            signals=app.signals;
            %uisave({'signals'},'ANC');
            file = uiputfile('Simu.anc');
            save(file,"signals");
        end

        % Button pushed function: compara
        function comparaButtonPushed(app, event)
            ComparaANC(app.signals);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ANCResultsVisualizerUIFigure = uifigure('Visible', 'off');
            app.ANCResultsVisualizerUIFigure.Name = 'ANC Results Visualizer';
            app.Slider = uislider(app.ANCResultsVisualizerUIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ANConoffButton = uibutton(app.ANCResultsVisualizerUIFigure, 'state');
            app.ANConoffButton.ValueChangedFcn = createCallbackFcn(app, @ANConoffButtonValueChanged, true);
            app.ANConoffButton.Text = 'ANC on/off';
            app.MicrophoneDropDownLabel = uilabel(app.ANCResultsVisualizerUIFigure);
            app.MicrophoneDropDownLabel.Text = 'Microphone';
            app.MicrophoneDropDown = uidropdown(app.ANCResultsVisualizerUIFigure);
            app.MicrophoneDropDown.Items = {'1', ''};
            app.MicrophoneDropDown.ValueChangedFcn = createCallbackFcn(app, @MicrophoneDropDownValueChanged, true);
            app.MicrophoneDropDown.Value = '1';
            app.PowerButtonGroup = uibuttongroup(app.ANCResultsVisualizerUIFigure);
            app.PowerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PowerButtonGroupSelectionChanged, true);
            app.PowerButtonGroup.Title = 'Power';
            app.LinealButton = uiradiobutton(app.PowerButtonGroup);
            app.LinealButton.Text = 'Lineal';
            app.LinealButton.Value = true;
            app.dBButton = uiradiobutton(app.PowerButtonGroup);
            app.dBButton.Text = 'dB';
            app.FrequencyButtonGroup = uibuttongroup(app.ANCResultsVisualizerUIFigure);
            app.FrequencyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FrequencyButtonGroupSelectionChanged, true);
            app.FrequencyButtonGroup.Title = 'Frequency';
            app.LinealButton_2 = uiradiobutton(app.FrequencyButtonGroup);
            app.LinealButton_2.Text = 'Lineal';
            app.LinealButton_2.Value = true;
            app.logButton = uiradiobutton(app.FrequencyButtonGroup);
            app.logButton.Text = 'log';
            app.Configura_Button = uibutton(app.ANCResultsVisualizerUIFigure, 'push');
            app.Configura_Button.ButtonPushedFcn = createCallbackFcn(app, @Configura_ButtonPushed, true);
            app.Configura_Button.Icon = 'settingscog_87317.png';
            app.Configura_Button.Text = '';
            app.Button = uibutton(app.ANCResultsVisualizerUIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = 'play.png';
            app.Button.Text = '';
            app.Guarda = uibutton(app.ANCResultsVisualizerUIFigure, 'push');
            app.Guarda.ButtonPushedFcn = createCallbackFcn(app, @GuardaButtonPushed, true);
            app.Guarda.Icon = 'Guardar.png';
            app.Guarda.Text = '';
            app.compara = uibutton(app.ANCResultsVisualizerUIFigure, 'push');
            app.compara.ButtonPushedFcn = createCallbackFcn(app, @comparaButtonPushed, true);
            app.compara.Icon = 'Comparar.png';
            app.compara.Text = '';
            app.eje_TF = uiaxes(app.ANCResultsVisualizerUIFigure);
            title(app.eje_TF, 'Spectrogram')
            xlabel(app.eje_TF, 't(s)')
            ylabel(app.eje_TF, 'f(Hz)')
            zlabel(app.eje_TF, 'Z')
            app.eje_T = uiaxes(app.ANCResultsVisualizerUIFigure);
            title(app.eje_T, 'Time evolution')
            xlabel(app.eje_T, 't(s)')
            zlabel(app.eje_T, 'Z')
            app.eje_F = uiaxes(app.ANCResultsVisualizerUIFigure);
            title(app.eje_F, 'Spectrum')
            xlabel(app.eje_F, 'f(Hz)')
            zlabel(app.eje_F, 'Z')
            % Show the figure after all components are created
            app.ANCResultsVisualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analizaANC(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ANCResultsVisualizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ANCResultsVisualizerUIFigure)
        end
    end
end