classdef conf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ComfigurationofreproductionandvisualizationUIFigure  matlab.ui.Figure
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        SampleRatekHzEditFieldLabel  matlab.ui.control.Label
        SampleRatekHzEditField       matlab.ui.control.NumericEditField
        WindowSizesamplesLabel       matlab.ui.control.Label
        WindowSizesamplesEditField   matlab.ui.control.NumericEditField
        OverlapEditFieldLabel        matlab.ui.control.Label
        OverlapEditField             matlab.ui.control.NumericEditField
        FFTSizeEditFieldLabel        matlab.ui.control.Label
        FFTSizeEditField             matlab.ui.control.NumericEditField
        AudioBufferSizesamplesLabel  matlab.ui.control.Label
        AudioBufferSizesamplesEditField  matlab.ui.control.NumericEditField
        Label_4                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        OkButton                     matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        OptimumBufferSize50msLabel   matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        OptimumGraphicalRefreshTime250msLabel  matlab.ui.control.Label
        GraphicalRefreshTimeNofAudioBufferLabel  matlab.ui.control.Label
        GraphicalRefreshTimeNofAudioBufferEditField  matlab.ui.control.NumericEditField
        InfoTextAreaLabel            matlab.ui.control.Label
        Info                         matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        CallingApp  % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.SampleRatekHzEditField.Value=mainapp.fs;
            app.WindowSizesamplesEditField.Value = mainapp.muestrasventana;
            app.OverlapEditField.Value = mainapp.solape;
            app.FFTSizeEditField.Value = mainapp.nfft_tf;
            app.AudioBufferSizesamplesEditField.Value = mainapp.bufferAudio;
            app.GraphicalRefreshTimeNofAudioBufferEditField.Value = mainapp.RefrescoGrafica;
            app.Info.Value=mainapp.Etiqueta;
            app.CallingApp=mainapp;
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app.ComfigurationofreproductionandvisualizationUIFigure)
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            cambiagraficas=0;
            cambiaprocesa=0;
            cambiaaudio=0;
            
            if app.CallingApp.fs~=app.SampleRatekHzEditField.Value
                cambiagraficas=1;
            end
            
            if app.CallingApp.muestrasventana~=app.WindowSizesamplesEditField.Value | app.CallingApp.solape~=app.OverlapEditField.Value | app.CallingApp.nfft_tf~=app.FFTSizeEditField.Value
                cambiaprocesa=1;
            end
            
            if app.CallingApp.bufferAudio~=app.AudioBufferSizesamplesEditField.Value
                cambiaaudio=1;
            end
            app.CallingApp.RefrescoGrafica=app.GraphicalRefreshTimeNofAudioBufferEditField.Value;
            app.CallingApp.fs=app.SampleRatekHzEditField.Value;
            app.CallingApp.muestrasventana=app.WindowSizesamplesEditField.Value;
            app.CallingApp.solape=app.OverlapEditField.Value;
            app.CallingApp.nfft_tf=app.FFTSizeEditField.Value;
            app.CallingApp.bufferAudio=app.AudioBufferSizesamplesEditField.Value;
            app.CallingApp.Etiqueta=app.Info.Value;
            actualizaEtiqueta(app.CallingApp);


            if cambiaprocesa
                calcula_espectro(app.CallingApp);
                dibuja_tf(app.CallingApp);
                dibuja_f(app.CallingApp);
                dibuja_t(app.CallingApp);
            elseif cambiagraficas
                dibuja_tf(app.CallingApp);
                dibuja_f(app.CallingApp);
                dibuja_t(app.CallingApp);
            end
            
            if cambiaaudio
                resetea_audio(app.CallingApp);
            end
                
            
            delete(app.ComfigurationofreproductionandvisualizationUIFigure)
        end

        % Value changed function: SampleRatekHzEditField
        function SampleRatekHzEditFieldValueChanged(app, event)
            value = app.SampleRatekHzEditField.Value;
            app.AudioBufferSizesamplesEditField.Value=round(50*value);
            app.GraphicalRefreshTimeNofAudioBufferEditField.Value=round(5);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ComfigurationofreproductionandvisualizationUIFigure = uifigure('Visible', 'off');
            app.ComfigurationofreproductionandvisualizationUIFigure.Name = 'Comfiguration of reproduction and visualization';
            app.Label = uilabel(app.ComfigurationofreproductionandvisualizationUIFigure);
            app.Label.Text = '(1-48)';
            app.Label_2 = uilabel(app.ComfigurationofreproductionandvisualizationUIFigure);
            app.Label_2.Text = '(124-2048)';
            app.Label_3 = uilabel(app.ComfigurationofreproductionandvisualizationUIFigure);
            app.Label_3.Text = '25-75';
            app.SampleRatekHzEditFieldLabel = uilabel(app.ComfigurationofreproductionandvisualizationUIFigure);
            app.SampleRatekHzEditFieldLabel.Text = 'Sample Rate (kHz):';
            app.SampleRatekHzEditField = uieditfield(app.ComfigurationofreproductionandvisualizationUIFigure, 'numeric');
            app.SampleRatekHzEditField.Limits = [1 48];
            app.SampleRatekHzEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRatekHzEditFieldValueChanged, true);
            app.SampleRatekHzEditField.Value = 1;
            app.WindowSizesamplesLabel = uilabel(app.ComfigurationofreproductionandvisualizationUIFigure);
            app.WindowSizesamplesLabel.Text = 'Window Size (samples):';
            app.WindowSizesamplesEditField = uieditfield(app.ComfigurationofreproductionandvisualizationUIFigure, 'numeric');
            app.WindowSizesamplesEditField.Limits = [124 2048];
            app.WindowSizesamplesEditField.Value = 124;
            app.OverlapEditFieldLabel = uilabel(app.ComfigurationofreproductionandvisualizationUIFigure);
            app.OverlapEditFieldLabel.Text = 'Overlap (%):';
            app.OverlapEditField = uieditfield(app.ComfigurationofreproductionandvisualizationUIFigure, 'numeric');
            app.OverlapEditField.Limits = [25 75];
            app.OverlapEditField.Value = 25;
            app.FFTSizeEditFieldLabel = uilabel(app.ComfigurationofreproductionandvisualizationUIFigure);
            app.FFTSizeEditFieldLabel.Text = 'FFT Size:';
            app.FFTSizeEditField = uieditfield(app.ComfigurationofreproductionandvisualizationUIFigure, 'numeric');
            app.FFTSizeEditField.Limits = [2048 8192];
            app.FFTSizeEditField.Value = 2048;
            app.AudioBufferSizesamplesLabel = uilabel(app.ComfigurationofreproductionandvisualizationUIFigure);
            app.AudioBufferSizesamplesLabel.Text = 'Audio Buffer Size (samples*):';
            app.AudioBufferSizesamplesEditField = uieditfield(app.ComfigurationofreproductionandvisualizationUIFigure, 'numeric');
            app.AudioBufferSizesamplesEditField.Limits = [50 4098];
            app.AudioBufferSizesamplesEditField.Value = 256;
            app.Label_4 = uilabel(app.ComfigurationofreproductionandvisualizationUIFigure);
            app.Label_4.Text = '50-4098';
            app.Label_5 = uilabel(app.ComfigurationofreproductionandvisualizationUIFigure);
            app.Label_5.Text = '2048-8192';
            app.OkButton = uibutton(app.ComfigurationofreproductionandvisualizationUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.ComfigurationofreproductionandvisualizationUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OptimumBufferSize50msLabel = uilabel(app.ComfigurationofreproductionandvisualizationUIFigure);
            app.OptimumBufferSize50msLabel.Text = '*Optimum Buffer Size ~ 50ms';
            app.Label_6 = uilabel(app.ComfigurationofreproductionandvisualizationUIFigure);
            app.Label_6.Text = '1-240';
            app.OptimumGraphicalRefreshTime250msLabel = uilabel(app.ComfigurationofreproductionandvisualizationUIFigure);
            app.OptimumGraphicalRefreshTime250msLabel.Text = '*Optimum Graphical Refresh Time ~ 250ms';
            app.GraphicalRefreshTimeNofAudioBufferLabel = uilabel(app.ComfigurationofreproductionandvisualizationUIFigure);
            app.GraphicalRefreshTimeNofAudioBufferLabel.Text = 'Graphical Refresh Time (Nº of Audio Buffer*)';
            app.GraphicalRefreshTimeNofAudioBufferEditField = uieditfield(app.ComfigurationofreproductionandvisualizationUIFigure, 'numeric');
            app.GraphicalRefreshTimeNofAudioBufferEditField.Limits = [1 240];
            app.GraphicalRefreshTimeNofAudioBufferEditField.Value = 5;
            app.InfoTextAreaLabel = uilabel(app.ComfigurationofreproductionandvisualizationUIFigure);
            app.InfoTextAreaLabel.Text = 'Info:';
            app.Info = uitextarea(app.ComfigurationofreproductionandvisualizationUIFigure);
            % Show the figure after all components are created
            app.ComfigurationofreproductionandvisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = conf(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ComfigurationofreproductionandvisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ComfigurationofreproductionandvisualizationUIFigure)
        end
    end
end