classdef confcomp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SettingforattenationandspectralanalisyscalculationUIFigure  matlab.ui.Figure
        ForgottenFactorenveloeofpowersignalsEditFieldLabel  matlab.ui.control.Label
        betaEdit                        matlab.ui.control.NumericEditField
        overlapLabel                    matlab.ui.control.Label
        overlapEditField                matlab.ui.control.NumericEditField
        SteadystatedurationEditFieldLabel  matlab.ui.control.Label
        SteadystatedurationEditField    matlab.ui.control.NumericEditField
        FFTlengthEditFieldLabel         matlab.ui.control.Label
        FFTlengthEditField              matlab.ui.control.NumericEditField
        NumberofaveragesEditFieldLabel  matlab.ui.control.Label
        NumberofaveragesEditField       matlab.ui.control.NumericEditField
        CancelButton                    matlab.ui.control.Button
        OkButton                        matlab.ui.control.Button
        SpectralanalisysLabel           matlab.ui.control.Label
        AttenuationLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appCalling)
            app.betaEdit.Value=appCalling.Parametros_conf.Beta;
            app.overlapEditField.Value=appCalling.Parametros_conf.Solape;
            app.SteadystatedurationEditField.Value=appCalling.Parametros_conf.TamTotal;
            app.FFTlengthEditField.Value=appCalling.Parametros_conf.Nfft;
            app.NumberofaveragesEditField.Value=appCalling.Parametros_conf.Promedios;
            app.CallingApp=appCalling;
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app.SettingforattenationandspectralanalisyscalculationUIFigure)
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            
            NocambiaAt=app.CallingApp.Parametros_conf.Beta==app.betaEdit.Value;
            Nocambiaf=(app.CallingApp.Parametros_conf.Solape==app.overlapEditField.Value & app.CallingApp.Parametros_conf.TamTotal==app.SteadystatedurationEditField.Value & app.CallingApp.Parametros_conf.Nfft==app.FFTlengthEditField.Value & app.CallingApp.Parametros_conf.Promedios==app.NumberofaveragesEditField.Value);

            app.CallingApp.Parametros_conf.Beta=app.betaEdit.Value;
            app.CallingApp.Parametros_conf.Solape=app.overlapEditField.Value;
            app.CallingApp.Parametros_conf.TamTotal=app.SteadystatedurationEditField.Value;
            app.CallingApp.Parametros_conf.Nfft=app.FFTlengthEditField.Value;
            app.CallingApp.Parametros_conf.Promedios=app.NumberofaveragesEditField.Value;
            
           if NocambiaAt
           else
            recalculalineasAt(app.CallingApp);   
           end
            
           if Nocambiaf
           else
           recalculalineasf(app.CallingApp);
           end


            delete(app.SettingforattenationandspectralanalisyscalculationUIFigure)
            
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SettingforattenationandspectralanalisyscalculationUIFigure = uifigure('Visible', 'off');
            app.SettingforattenationandspectralanalisyscalculationUIFigure.Name = 'Setting for attenation and spectral analisys calculation';
            app.ForgottenFactorenveloeofpowersignalsEditFieldLabel = uilabel(app.SettingforattenationandspectralanalisyscalculationUIFigure);
            app.ForgottenFactorenveloeofpowersignalsEditFieldLabel.Text = 'Forgotten Factor (enveloe of power signals)';
            app.betaEdit = uieditfield(app.SettingforattenationandspectralanalisyscalculationUIFigure, 'numeric');
            app.betaEdit.Limits = [0 1];
            app.overlapLabel = uilabel(app.SettingforattenationandspectralanalisyscalculationUIFigure);
            app.overlapLabel.Text = 'overlap(%)';
            app.overlapEditField = uieditfield(app.SettingforattenationandspectralanalisyscalculationUIFigure, 'numeric');
            app.overlapEditField.Limits = [0 100];
            app.SteadystatedurationEditFieldLabel = uilabel(app.SettingforattenationandspectralanalisyscalculationUIFigure);
            app.SteadystatedurationEditFieldLabel.Text = 'Steady state duration (%)';
            app.SteadystatedurationEditField = uieditfield(app.SettingforattenationandspectralanalisyscalculationUIFigure, 'numeric');
            app.SteadystatedurationEditField.Limits = [0 100];
            app.FFTlengthEditFieldLabel = uilabel(app.SettingforattenationandspectralanalisyscalculationUIFigure);
            app.FFTlengthEditFieldLabel.Text = 'FFT length';
            app.FFTlengthEditField = uieditfield(app.SettingforattenationandspectralanalisyscalculationUIFigure, 'numeric');
            app.FFTlengthEditField.Limits = [256 32768];
            app.FFTlengthEditField.Value = 256;
            app.NumberofaveragesEditFieldLabel = uilabel(app.SettingforattenationandspectralanalisyscalculationUIFigure);
            app.NumberofaveragesEditFieldLabel.Text = 'Number of averages';
            app.NumberofaveragesEditField = uieditfield(app.SettingforattenationandspectralanalisyscalculationUIFigure, 'numeric');
            app.NumberofaveragesEditField.Limits = [1 100];
            app.NumberofaveragesEditField.Value = 1;
            app.CancelButton = uibutton(app.SettingforattenationandspectralanalisyscalculationUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OkButton = uibutton(app.SettingforattenationandspectralanalisyscalculationUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.SpectralanalisysLabel = uilabel(app.SettingforattenationandspectralanalisyscalculationUIFigure);
            app.SpectralanalisysLabel.Text = 'Spectral analisys:';
            app.AttenuationLabel = uilabel(app.SettingforattenationandspectralanalisyscalculationUIFigure);
            app.AttenuationLabel.Text = 'Attenuation ';
            % Show the figure after all components are created
            app.SettingforattenationandspectralanalisyscalculationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = confcomp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SettingforattenationandspectralanalisyscalculationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SettingforattenationandspectralanalisyscalculationUIFigure)
        end
    end
end